classdef PressureSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button_5        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        SendDataButton  matlab.ui.control.Button
        GenerateButton  matlab.ui.control.Button
        LoadDataButton  matlab.ui.control.Button
        NextButton      matlab.ui.control.Button
        PreviousButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Output % Description
        NumTrials % Description
        NumConditions % Description
        Trials % Description
        Condition % Description
        Preference
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.Condition = app.Condition+1;
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.Condition > 1
                app.Condition = app.Condition-1;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.NumTrials = evalin("base","Num_trials");
            app.NumConditions = evalin("base","Num_Conditions");
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.Trials = zeros(app.NumTrials,app.NumConditions);
            for i = 1:app.NumTrials
                x = randperm(app.NumConditions);
                app.Trials(i,:) = (5/(app.NumConditions-1))*(x-1)+1;
            end
            app.Condition = 1;
            assignin("base","Trials",app.Trials);

        end

        % Button pushed function: SendDataButton
        function SendDataButtonPushed(app, event)
            m = ceil(app.Condition/app.NumConditions);
            n = mod(app.Condition,app.NumConditions);
            if n == 0
                n = app.NumConditions;
            end
            app.Output = app.Trials(m,n);
            assignin("base","Pressure",app.Output);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Preference = 1;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Preference = 2;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Preference = 3;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Preference = 4;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Preference = 5;
            assignin("base","Preference",app.Preference);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SendDataButton = uibutton(app.UIFigure, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PressureSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end