classdef PressureSelection_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SessionDropDown          matlab.ui.control.DropDown
        SessionDropDownLabel     matlab.ui.control.Label
        ExperimentPhaseDropDown  matlab.ui.control.DropDown
        ExperimentPhaseLabel     matlab.ui.control.Label
        PressureLabel            matlab.ui.control.Label
        CountdownLabel           matlab.ui.control.Label
        ConditionLabel           matlab.ui.control.Label
        Button_10                matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        SendDataButton           matlab.ui.control.Button
        GenerateButton           matlab.ui.control.Button
        LoadDataButton           matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        PreviousButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Output % Description
        NumTrials % Description
        NumConditions % Description
        Trials % Description
        Condition % Description
        Preference        
    end
   
    
    methods (Access = private)
        % Update the ConditionLabel with the current condition value
        function updateConditionLabel(app)
            app.ConditionLabel.Text = sprintf('Current trial: %d', app.Condition);
        end
        
        % Helper function to generate and play a tone
        function generateAndPlaySound(app, frequency, duration, c)
            fs = 44100; % Sample rate
            t = 0:1/fs:duration;
            y = sin(2 * pi * frequency * t);
            sound(y, fs);

            % Set auditory_input to 1 and 2 for single beeps
            if c == 1
                app.setAuditoryInput(1);
            else
                app.setAuditoryInput(2);
            end
        end

        % Helper function to play a double beep sound
        function doubleBeepSound(app)
            app.generateAndPlaySound(740, 0.3, 2); % First beep
            pause(0.5); % Small pause between beeps
            app.generateAndPlaySound(740, 0.3, 2); % Second beep
            
            % Reactivate Next and Previous buttons after the double beep
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Reactivate preference buttons
            app.setPreferenceButtonsState('on');

            % Set auditory_input to 0 for double beep
            app.setAuditoryInput(0);

        end

        
        % Method to start the countdown timer
        function startCountdown(app)
            countdownTime = 10;
            app.updateCountdownLabel(countdownTime);
            
            % Create a timer to update the countdown every second
            t = timer('ExecutionMode', 'fixedRate', ...
                      'Period', 1, ...
                      'TasksToExecute', countdownTime, ...
                      'TimerFcn', @(~,~) app.updateCountdown(countdownTime));
            start(t);
        end
        
        % Method to update the countdown timer
        function updateCountdown(app, countdownTime)
            persistent currentTime
            if isempty(currentTime)
                currentTime = countdownTime;
            end
            
            currentTime = currentTime - 1;
            app.updateCountdownLabel(currentTime);
            
            if currentTime <= 0
                stop(timerfindall); % Stop all timers
                delete(timerfindall); % Delete all timers
                currentTime = [];

                % Reactivate Send Data button
                app.SendDataButton.Enable = 'on';
            end

        end
        
        % Method to update the countdown label
        function updateCountdownLabel(app, time)
            app.CountdownLabel.Text = sprintf('Rest time: %d', time);
        end


        % Method to update the Pressure label
        function updatePressureLabel(app)
            if app.Output == 1
                P = 1;
            elseif app.Output == 2
                P = 3;
            else
                P = 6;
            end
            app.PressureLabel.Text = sprintf('Pressure: %d', P);
        end


        % Add a function to Deactivate and Reactivate the Preference Buttons
        function setPreferenceButtonsState(app, state)
            app.Button.Enable = state;
            app.Button_2.Enable = state;
            app.Button_3.Enable = state;
            app.Button_4.Enable = state;
            app.Button_5.Enable = state;
            app.Button_6.Enable = state;
            app.Button_7.Enable = state;
            app.Button_8.Enable = state;
            app.Button_9.Enable = state;
            app.Button_10.Enable = state;
        end

        
        function setAuditoryInput(app, value)
            % Set the auditory_input variable in the base workspace
            assignin('base', 'auditory_input', value);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.Condition = app.Condition+1;
            app.updateConditionLabel(); % Update label

            % Deactivate Next and Previous buttons
            app.NextButton.Enable = 'off';
            app.PreviousButton.Enable = 'off';
        
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.Condition > 1
                app.Condition = app.Condition-1;
                app.updateConditionLabel(); % Update label

                % Deactivate Next and Previous buttons
                app.NextButton.Enable = 'off';
                app.PreviousButton.Enable = 'off';
            
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.NumTrials = evalin("base","Num_trials");
            app.NumConditions = evalin("base","Num_Conditions");
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.Trials = zeros(app.NumTrials,app.NumConditions);
            for i = 1:app.NumTrials
                app.Trials(i,:) = randperm(app.NumConditions);
                % app.Trials(i,:) = (5/(app.NumConditions-1))*(x-1)+1;
            end
            app.Condition = 1;
            app.updateConditionLabel(); % Update label
            assignin("base","Trials",app.Trials);

            % Deactivate Next and Previous buttons
            app.GenerateButton.Enable = 'off';

        end

        % Button pushed function: SendDataButton
        function SendDataButtonPushed(app, event)
            m = ceil(app.Condition/app.NumConditions);
            n = mod(app.Condition,app.NumConditions);
            if n == 0
                n = app.NumConditions;
            end
            app.Output = app.Trials(m,n);
            app.updatePressureLabel();
            
            app.generateAndPlaySound(1480, 0.5, 1);

            t1 = timer('StartDelay', 2, 'TimerFcn', @(~,~) assignin("base","Pressure",app.Output));
            start(t1)

            % assignin("base","Pressure",app.Output);

            % Deactivate Next, Previous, and Send Data buttons
            app.NextButton.Enable = 'off';
            app.PreviousButton.Enable = 'off';
            app.SendDataButton.Enable = 'off';

            % Create a timer for the initial beep
            t2 = timer('StartDelay', 4, 'TimerFcn', @(~,~) app.generateAndPlaySound(740, 0.5, 2));
            start(t2);

            % Create a timer for the double beep
            t3 = timer('StartDelay', 24, 'TimerFcn', @(~,~) app.doubleBeepSound);
            start(t3);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Preference = 1;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Preference = 2;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Preference = 3;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Preference = 4;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Preference = 5;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Preference = 6;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Preference = 7;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Preference = 8;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Preference = 9;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.Preference = 10;
            assignin("base","Preference",app.Preference);

            app.setPreferenceButtonsState('off'); % Deactivate preference buttons

            % Reactivate Next and Previous buttons
            app.NextButton.Enable = 'on';
            app.PreviousButton.Enable = 'on';

            % Start the countdown timer after the double beep
            app.startCountdown();

            % Specify the moment of pressing the score on LSL
            S = evalin('base', 'score_press');
            assignin('base', 'score_press', S + app.Preference);
        end

        % Value changed function: ExperimentPhaseDropDown
        function ExperimentPhaseDropDownValueChanged(app, event)
            
            value = app.ExperimentPhaseDropDown.Value;
            switch value
                case 'No PAM'
                    phaseValue = 1;
                case 'Familiarization'
                    phaseValue = 2;
                case 'Experiment'
                    phaseValue = 3;
                otherwise
                    phaseValue = NaN; % In case of unexpected values
            end
            assignin('base', 'ExperimentPhase', phaseValue); % Send to workspace
            disp(['Experiment Phase set to: ', num2str(phaseValue)]);

        end

        % Callback function: not associated with a component
        function SessionTextAreaValueChanged(app, event)

            sessionNumber = str2double(app.SessionEditField.Value); % Convert input to a number
            if isnan(sessionNumber)
                disp('Invalid session number. Please enter a numeric value.');
            else
                assignin('base', 'SessionNumber', sessionNumber); % Send to workspace
                disp(['Session Number set to: ', num2str(sessionNumber)]);
            end

        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            
            value = app.SessionDropDown.Value;
            switch value
                case '1'
                    sessionNumber = 1;
                case '2'
                    sessionNumber = 2;
                case '3'
                    sessionNumber = 3;
                case '4'
                    sessionNumber = 4;
                case '5'
                    sessionNumber = 5;
                otherwise
                    phaseValue = NaN; % In case of unexpected values
            end
            assignin('base', 'SessionNumber', sessionNumber); % Send to workspace
            disp(['Session Number set to: ', num2str(sessionNumber)]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SendDataButton = uibutton(app.UIFigure, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '10';
            app.ConditionLabel = uilabel(app.UIFigure);
            app.ConditionLabel.Text = 'Current trial: 0';
            app.CountdownLabel = uilabel(app.UIFigure);
            app.CountdownLabel.Text = 'Rest time: 10';
            app.PressureLabel = uilabel(app.UIFigure);
            app.PressureLabel.Text = 'Pressure: ';
            app.ExperimentPhaseLabel = uilabel(app.UIFigure);
            app.ExperimentPhaseLabel.Text = 'Experiment Phase:';
            app.ExperimentPhaseDropDown = uidropdown(app.UIFigure);
            app.ExperimentPhaseDropDown.Items = {'No PAM', 'Familiarization', 'Experiment'};
            app.ExperimentPhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentPhaseDropDownValueChanged, true);
            app.ExperimentPhaseDropDown.Value = 'No PAM';
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session:';
            app.SessionDropDown = uidropdown(app.UIFigure);
            app.SessionDropDown.Items = {'1', '2', '3', '4', '5'};
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.SessionDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PressureSelection_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end