classdef mark_bad_trials_of_EEG_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        Label2                matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        LogTextArea           matlab.ui.control.TextArea
        UnmarkButton          matlab.ui.control.Button
        MarkasBadButton       matlab.ui.control.Button
        PreviousButton        matlab.ui.control.Button
        NextButton            matlab.ui.control.Button
        LoadEEGsignalsButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        subject_id;
        Exp_time;
        EEG;
        trial;
        bad_trials_EEG_based;
        XLimits_EEG;
        N_Trials;

        indx1;
        indx2;
    end
    
    methods (Access = private)
        
        % Function to update the trial plot
        function updateTrialPlot(app)
            app.UIAxes.XLim = [app.XLimits_EEG(app.trial, 1), ...
                app.XLimits_EEG(app.trial, 2)];

            min_e = 1.05*( min(min(app.EEG.data(:, app.indx1(app.trial):app.indx2(app.trial) ))) );
            max_e = 1.05*( max(max(app.EEG.data(:, app.indx1(app.trial):app.indx2(app.trial) ))) );
            app.UIAxes.YLim = [min_e max_e];

            % Update the trial label
            app.Label.Text = ...
                sprintf('Trial %d of %d', app.trial, app.N_Trials);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadEEGsignalsButton
        function LoadEEGsignalsButtonPushed(app, event)
            % Retrieve EEG data from the base workspace
            app.subject_id = evalin('base', 'subject_id');
            app.N_Trials   = evalin('base', 'N_Trials');

            app.Exp_time = evalin('base', 'All_Experiment_time');
            app.EEG = evalin('base', 'EEG');
            app.bad_trials_EEG_based = evalin('base', 'bad_trials_EEG_based');

            app.trial = 1; % Reset trial to 0
            app.bad_trials_EEG_based = []; % define the vector of bad trials 

            
            % get the events and plot all EEG signals on top of each other
            all_types = {app.EEG.event.type};
            app.XLimits_EEG = zeros(app.N_Trials, 2);
            app.indx1 = zeros(app.N_Trials, 1);
            app.indx2 = zeros(app.N_Trials, 1);
            indx3 = zeros(app.N_Trials, 1);
            indx4 = zeros(app.N_Trials, 1);
            indx5 = zeros(app.N_Trials, 1);
            for i = 1:app.N_Trials
                a1 = find(strcmp(all_types, 'TS_Trial_Start'), i);
                app.indx1(i,1) = app.EEG.event(a1(i)).latency;
                a2 = find(strcmp(all_types, 'TE_Trial_End'), i);
                app.indx2(i,1) = app.EEG.event(a2(i)).latency;
                
                app.XLimits_EEG(i, :) = ...
                    [app.EEG.times(app.indx1(i,1)) app.EEG.times(app.indx2(i,1))];
            
                a3 = find(strcmp(all_types, 'PC_Pressure_Change'), i);
                indx3(i,1) = app.EEG.event(a3(i)).latency;
            
                a4 = find(strcmp(all_types, 'SB_Start_Beep'), i);
                indx4(i,1) = app.EEG.event(a4(i)).latency;

                a5 = find(strcmp(all_types, 'FB_Finish_Beep'), i);
                indx5(i,1) = app.EEG.event(a5(i)).latency;
            end


            % Plot the data
            plot(app.UIAxes, app.EEG.times, app.EEG.data, "Color", 0.7*[1 1 1]);
            hold(app.UIAxes, 'on')

            plot(app.UIAxes, app.EEG.times, mean(app.EEG.data, 1), ...
                'Color', 'k', 'LineWidth', 0.5)
            plot(app.UIAxes, app.EEG.times, mean(app.EEG.data, 1) + 2*std(app.EEG.data, 1), ...
                'Color', 'k', 'LineWidth', 0.5)
            plot(app.UIAxes, app.EEG.times, mean(app.EEG.data, 1) - 2*std(app.EEG.data, 1), ...
                'Color', 'k', 'LineWidth', 0.5)
            

            app.UIAxes.XLim = [app.XLimits_EEG(app.trial,1) app.XLimits_EEG(app.trial,2)];
            min_e = 1.05*( min(min(app.EEG.data(:, app.indx1(app.trial):app.indx2(app.trial) ))) );
            max_e = 1.05*( max(max(app.EEG.data(:, app.indx1(app.trial):app.indx2(app.trial) ))) );
            app.UIAxes.YLim = [min_e max_e];
            

            % Plot main trial evevnts as xlines
            xline(app.UIAxes, ...
                app.EEG.times(indx3), ...
                'Color', 'k', ...
                'LineStyle', '--', 'LineWidth', 1)

            xline(app.UIAxes, ...
                app.EEG.times(indx4), ...
                'Color', 'g', ...
                'LineStyle', '-', 'LineWidth', 0.5)

            xline(app.UIAxes, ...
                app.EEG.times(indx5), ...
                'Color', 'r', ...
                'LineStyle', '-', 'LineWidth', 0.5)

            % Initialize the labels
            app.Label.Text = sprintf('Trial 1 of %d', app.N_Trials);
            app.Label2.Text = sprintf('Num Marked Trials = %d', 0);
          
            % Disable the Load Data button
            app.LoadEEGsignalsButton.Enable = 'off';

            % % Update the trial label
            % app.Label1.Text = sprintf('Trial %d of %d', app.trial, size(app.XLimits, 1));
            % 
            % % % Add click callback to the plot
            % % app.UIAxes.ButtonDownFcn = @(src, event)onPlotClick(app, src, event);
            % 
            % % Add click callback to the plot
            % app.UIFigure.WindowButtonDownFcn = @(src, event)onPlotClick(app, src, event);

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if app.trial < app.N_Trials
                app.trial = app.trial + 1;
                updateTrialPlot(app);
            end

        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            if app.trial > 1
                app.trial = app.trial - 1;
                updateTrialPlot(app);
            end
            
        end

        % Button pushed function: MarkasBadButton
        function MarkasBadButtonPushed(app, event)
            
            if ~ismember(app.trial, app.bad_trials_EEG_based)
                app.bad_trials_EEG_based = ...
                    cat(2, app.bad_trials_EEG_based, app.trial);
    
                app.Label2.Text = sprintf('Num Marked Trials = %d', ...
                    length(app.bad_trials_EEG_based));
    
                % Log the action
                logMessage = sprintf('Trial %d Marked.', app.trial);
                app.LogTextArea.Value{end+1} = logMessage;
    
                % Update the bad_trials_EEG_based in the base workspace
                assignin('base', 'bad_trials_EEG_based', app.bad_trials_EEG_based);
            end

        end

        % Button pushed function: UnmarkButton
        function UnmarkButtonPushed(app, event)
            
            if ismember(app.trial, app.bad_trials_EEG_based)

                v = app.bad_trials_EEG_based;
                app.bad_trials_EEG_based(v == app.trial) = [];
                
    
                app.Label2.Text = sprintf('Num Marked Trials = %d', ...
                    length(app.bad_trials_EEG_based));
    
                % Log the action
                logMessage = sprintf('Trial %d Unmarked.', app.trial);
                app.LogTextArea.Value{end+1} = logMessage;
    
                % Update the bad_trials_EEG_based in the base workspace
                assignin('base', 'bad_trials_EEG_based', app.bad_trials_EEG_based);
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {694, 694};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {833, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'All EEG channels on top of each other')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, '\muV')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.LoadEEGsignalsButton = uibutton(app.GridLayout2, 'push');
            app.LoadEEGsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEEGsignalsButtonPushed, true);
            app.LoadEEGsignalsButton.Text = 'Load EEG signals';
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.GridLayout2, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.MarkasBadButton = uibutton(app.GridLayout2, 'push');
            app.MarkasBadButton.ButtonPushedFcn = createCallbackFcn(app, @MarkasBadButtonPushed, true);
            app.MarkasBadButton.Text = 'Mark as Bad';
            app.UnmarkButton = uibutton(app.GridLayout2, 'push');
            app.UnmarkButton.ButtonPushedFcn = createCallbackFcn(app, @UnmarkButtonPushed, true);
            app.UnmarkButton.Text = 'Unmark';
            app.LogTextArea = uitextarea(app.GridLayout2);
            app.Label = uilabel(app.GridLayout2);
            app.Label2 = uilabel(app.GridLayout2);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mark_bad_trials_of_EEG_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end