classdef PreferenceSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        VeryDifficultLabel  matlab.ui.control.Label
        VeryEasyLabel       matlab.ui.control.Label
        Button_5            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Preference % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Preference = 1;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Preference = 2;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Preference = 3;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Preference = 4;
            assignin("base","Preference",app.Preference);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Preference = 5;
            assignin("base","Preference",app.Preference);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.VeryEasyLabel = uilabel(app.UIFigure);
            app.VeryEasyLabel.Text = 'Very Easy';
            app.VeryDifficultLabel = uilabel(app.UIFigure);
            app.VeryDifficultLabel.Text = 'Very Difficult';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreferenceSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end